classdef plant_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CreateparameterfilesButton    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ParameterDropDownLabel        matlab.ui.control.Label
        ParameterDropDown             matlab.ui.control.DropDown
        ImageHeightEditFieldLabel     matlab.ui.control.Label
        ImageHeightEditField          matlab.ui.control.NumericEditField
        RightxboundEditFieldLabel     matlab.ui.control.Label
        RightxboundEditField          matlab.ui.control.NumericEditField
        FrontyboundEditFieldLabel     matlab.ui.control.Label
        FrontyboundEditField          matlab.ui.control.NumericEditField
        BackyboundEditFieldLabel      matlab.ui.control.Label
        BackyboundEditField           matlab.ui.control.NumericEditField
        TextparamEditFieldLabel       matlab.ui.control.Label
        TextparamEditField            matlab.ui.control.EditField
        GenerateparameterfilesButton  matlab.ui.control.Button
        booleanDropDownLabel          matlab.ui.control.Label
        booleanDropDown               matlab.ui.control.DropDown
        LeftxboundEditFieldLabel      matlab.ui.control.Label
        LeftxboundEditField           matlab.ui.control.NumericEditField
        ViewdepthimageButton          matlab.ui.control.Button
        AnalyseplantsButton           matlab.ui.control.Button
        AnalyseallButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        im_height = 424;
        im_width = 512;
        im_no = 11;
        plant_axis = 'y';
        do_remove_plane = true;
        do_remove_pot = true;
        bounding_box = [250, 1500, 1, 1500];
        save_pc = true;
        save_measurements = true;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateparameterfilesButton
        function CreateparameterfilesButtonPushed(app, event)
            app.CreateparameterfilesButton.Visible = 'off';
            
            app.AnalyseallButton.Visible = 'off';
            app.AnalyseplantsButton.Visible = 'off';
            
            app.GenerateparameterfilesButton.Visible = 'on';
            
            app.ParameterDropDown.Visible = 'on';
            app.ParameterDropDownLabel.Visible = 'on';
            
            app.ImageHeightEditField.Visible = 'on';
            app.ImageHeightEditFieldLabel.Visible = 'on';
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            value = app.ParameterDropDown.Value;       
                
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDownLabel.Visible = 'off';
                
            app.TextparamEditField.Visible = 'off';
            app.TextparamEditFieldLabel.Visible = 'off';
            
            app.RightxboundEditField.Visible = 'off';
            app.RightxboundEditFieldLabel.Visible = 'off';
            
            app.LeftxboundEditField.Visible = 'off';
            app.LeftxboundEditFieldLabel.Visible = 'off';
            
            app.FrontyboundEditField.Visible = 'off';
            app.FrontyboundEditFieldLabel.Visible = 'off';
            
            app.BackyboundEditField.Visible = 'off';
            app.BackyboundEditFieldLabel.Visible = 'off';
            
            if strcmp(value, 'Vertical axis')
                app.TextparamEditFieldLabel.Text = value;
                
                app.TextparamEditField.Visible = 'on';
                app.TextparamEditFieldLabel.Visible = 'on';
                
                app.TextparamEditField.Value = app.plant_axis;
                
            elseif strcmp(value, 'Remove plane') || strcmp(value, 'Remove pot') ...
                    || strcmp(value, 'Save point cloud') || strcmp(value, 'Save measurements')
                
                app.booleanDropDownLabel.Text = value;
                
                app.booleanDropDown.Visible = 'on';
                app.booleanDropDownLabel.Visible = 'on';
                
            elseif strcmp(value, 'Bounding box')                    
                    app.LeftxboundEditField.Visible = 'on';
                    app.LeftxboundEditFieldLabel.Visible = 'on';
                    app.LeftxboundEditField.Value = app.bounding_box(1);
                    
                    app.RightxboundEditField.Visible = 'on';
                    app.RightxboundEditFieldLabel.Visible = 'on';
                    app.RightxboundEditField.Value = app.bounding_box(2);
                    
                    app.FrontyboundEditField.Visible = 'on';
                    app.FrontyboundEditFieldLabel.Visible = 'on';
                    app.FrontyboundEditField.Value = app.bounding_box(3);
                    
                    app.BackyboundEditField.Visible = 'on';
                    app.BackyboundEditFieldLabel.Visible = 'on';
                    app.BackyboundEditField.Value = app.bounding_box(4);
                    
            else                    
                app.ImageHeightEditFieldLabel.Text = value;
                
                app.ImageHeightEditField.Visible = 'on';
                app.ImageHeightEditFieldLabel.Visible = 'on';
                
                if strcmp(value, 'Image Height')
                    app.ImageHeightEditField.Value = app.im_height;
                    
                elseif strcmp(value, 'Image Width')
                    app.ImageHeightEditField.Value = app.im_width;
                
                elseif strcmp(value, 'Number of views')
                    app.ImageHeightEditField.Value = app.im_no;
                end
            end
        end

        % Button pushed function: ViewdepthimageButton
        function ViewdepthimageButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.png', 'Select a depth image');
            
            drawnow;
            figure(app.UIFigure)
            
            depth_im = imread(fullfile(file_path, file_name));
            
            pc = depthImage2PC(depth_im);
            
            figure;
            pcshow(pc);
        end

        % Button pushed function: GenerateparameterfilesButton
        function GenerateparameterfilesButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder to generate files for all its bottom level folders');
            
            drawnow;
            figure(app.UIFigure)
            
            end_paths = get_end_paths(folder_path, {});
            
            for i = 1:numel(end_paths)
                path = end_paths{i};
                generate_parameter_file(path, app.im_height, app.im_width, ...
                    app.im_no, app.plant_axis, app.do_remove_plane, app.do_remove_pot, ...
                    app.bounding_box, app.save_pc, app.save_measurements);  
            end     
            
            app.CreateparameterfilesButton.Visible = 'on';
            
            app.AnalyseallButton.Visible = 'on';
            app.AnalyseplantsButton.Visible = 'on';
            
            app.GenerateparameterfilesButton.Visible = 'off';
            
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDownLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditFieldLabel.Visible = 'off';
            
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDownLabel.Visible = 'off';
                
            app.TextparamEditField.Visible = 'off';
            app.TextparamEditFieldLabel.Visible = 'off';
            
            app.RightxboundEditField.Visible = 'off';
            app.RightxboundEditFieldLabel.Visible = 'off';
            
            app.LeftxboundEditField.Visible = 'off';
            app.LeftxboundEditFieldLabel.Visible = 'off';
            
            app.FrontyboundEditField.Visible = 'off';
            app.FrontyboundEditFieldLabel.Visible = 'off';
            
            app.BackyboundEditField.Visible = 'off';
            app.BackyboundEditFieldLabel.Visible = 'off';
        end

        % Value changed function: ImageHeightEditField
        function ImageHeightEditFieldValueChanged(app, event)
            value = app.ImageHeightEditField.Value;
            
            if strcmp(value, 'Image Height')
                app.im_height = value;
                
            elseif strcmp(value, 'Image Width')
                app.im_width = value;
            
            elseif strcmp(value, 'Number of views')
                app.im_no = value;
            end 
        end

        % Value changed function: TextparamEditField
        function TextparamEditFieldValueChanged(app, event)
            value = app.TextparamEditField.Value;
            
            app.plant_axis = value;
        end

        % Value changed function: booleanDropDown
        function booleanDropDownValueChanged(app, event)
            value = app.booleanDropDown.Value;
            
            if strcmp(value, 'true')
                value = true;
            else
                value = false;
            end
            
            if strcmp(value, 'Remove plane')
                app.do_remove_plane = value;
                
            elseif strcmp(value, 'Remove pot')
                app.do_remove_pot = value;
                
            elseif strcmp(value, 'Save point cloud')
                app.save_pc = value;
                
            elseif trcmp(value, 'Save measurements')
                app.save_measurements = value;
            end
        end

        % Value changed function: LeftxboundEditField
        function LeftxboundEditFieldValueChanged(app, event)
            value = app.LeftxboundEditField.Value;
            
            app.bounding_box(1) = value;
        end

        % Value changed function: RightxboundEditField
        function RightxboundEditFieldValueChanged(app, event)
            value = app.RightxboundEditField.Value;
            
            app.bounding_box(2) = value;
        end

        % Value changed function: FrontyboundEditField
        function FrontyboundEditFieldValueChanged(app, event)
            value = app.FrontyboundEditField.Value;
            
            app.bounding_box(3) = value;
        end

        % Value changed function: BackyboundEditField
        function BackyboundEditFieldValueChanged(app, event)
            value = app.BackyboundEditField.Value;
            
            app.bounding_box(4) = value;
        end

        % Button pushed function: AnalyseplantsButton
        function AnalyseplantsButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.UIFigure)
            
            plant_ids = inputdlg('Enter comma-seperated IDs of the plants to analyse, e.g., ID_1,ID_2,ID_3');
            
            plant_ids = split(plant_ids, ',');
            
            for i = 1:numel(plant_ids)
                plant_id = plant_ids{i};
                [pcs, measurements_4D] = analyse_over_time(folder_path, plant_id);
            end
        end

        % Button pushed function: AnalyseallButton
        function AnalyseallButtonPushed(app, event)
            folder_path = uigetdir('', 'Choose a top level folder which contains the folders for each timepoint');
            
            drawnow;
            figure(app.UIFigure)
            
            end_paths = get_end_paths(folder_path, {});
            
            for i = 1:numel(end_paths)
                split_path = split(end_paths{i}, '\');
                plant_id = split_path{end};
                [pcs, measurements_4D] = analyse_over_time(folder_path, plant_id);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CreateparameterfilesButton = uibutton(app.UIFigure, 'push');
            app.CreateparameterfilesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateparameterfilesButtonPushed, true);
            app.CreateparameterfilesButton.Text = 'Create parameter files';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParameterDropDownLabel = uilabel(app.UIFigure);
            app.ParameterDropDownLabel.Visible = 'off';
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.UIFigure);
            app.ParameterDropDown.Items = {'Image Height', 'Image Width', 'Number of views', 'Vertical axis', 'Remove plane', 'Remove pot', 'Bounding box', 'Save point cloud', 'Save measurements'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDown.Value = 'Image Height';
            app.ImageHeightEditFieldLabel = uilabel(app.UIFigure);
            app.ImageHeightEditFieldLabel.Visible = 'off';
            app.ImageHeightEditFieldLabel.Text = 'Image Height';
            app.ImageHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageHeightEditField.Limits = [1 Inf];
            app.ImageHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ImageHeightEditFieldValueChanged, true);
            app.ImageHeightEditField.Visible = 'off';
            app.ImageHeightEditField.Value = 424;
            app.RightxboundEditFieldLabel = uilabel(app.UIFigure);
            app.RightxboundEditFieldLabel.Visible = 'off';
            app.RightxboundEditFieldLabel.Text = 'Right x bound';
            app.RightxboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightxboundEditField.ValueChangedFcn = createCallbackFcn(app, @RightxboundEditFieldValueChanged, true);
            app.RightxboundEditField.Visible = 'off';
            app.FrontyboundEditFieldLabel = uilabel(app.UIFigure);
            app.FrontyboundEditFieldLabel.Visible = 'off';
            app.FrontyboundEditFieldLabel.Text = 'Front y bound';
            app.FrontyboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrontyboundEditField.ValueChangedFcn = createCallbackFcn(app, @FrontyboundEditFieldValueChanged, true);
            app.FrontyboundEditField.Visible = 'off';
            app.BackyboundEditFieldLabel = uilabel(app.UIFigure);
            app.BackyboundEditFieldLabel.Visible = 'off';
            app.BackyboundEditFieldLabel.Text = 'Back y bound';
            app.BackyboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.BackyboundEditField.ValueChangedFcn = createCallbackFcn(app, @BackyboundEditFieldValueChanged, true);
            app.BackyboundEditField.Visible = 'off';
            app.TextparamEditFieldLabel = uilabel(app.UIFigure);
            app.TextparamEditFieldLabel.Visible = 'off';
            app.TextparamEditFieldLabel.Text = 'Text param';
            app.TextparamEditField = uieditfield(app.UIFigure, 'text');
            app.TextparamEditField.ValueChangedFcn = createCallbackFcn(app, @TextparamEditFieldValueChanged, true);
            app.TextparamEditField.Visible = 'off';
            app.GenerateparameterfilesButton = uibutton(app.UIFigure, 'push');
            app.GenerateparameterfilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateparameterfilesButtonPushed, true);
            app.GenerateparameterfilesButton.Visible = 'off';
            app.GenerateparameterfilesButton.Text = 'Generate parameter files ';
            app.booleanDropDownLabel = uilabel(app.UIFigure);
            app.booleanDropDownLabel.Visible = 'off';
            app.booleanDropDownLabel.Text = 'boolean';
            app.booleanDropDown = uidropdown(app.UIFigure);
            app.booleanDropDown.Items = {'true', 'false'};
            app.booleanDropDown.ValueChangedFcn = createCallbackFcn(app, @booleanDropDownValueChanged, true);
            app.booleanDropDown.Visible = 'off';
            app.booleanDropDown.Value = 'true';
            app.LeftxboundEditFieldLabel = uilabel(app.UIFigure);
            app.LeftxboundEditFieldLabel.Visible = 'off';
            app.LeftxboundEditFieldLabel.Text = 'Left x bound';
            app.LeftxboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftxboundEditField.ValueChangedFcn = createCallbackFcn(app, @LeftxboundEditFieldValueChanged, true);
            app.LeftxboundEditField.Visible = 'off';
            app.ViewdepthimageButton = uibutton(app.UIFigure, 'push');
            app.ViewdepthimageButton.ButtonPushedFcn = createCallbackFcn(app, @ViewdepthimageButtonPushed, true);
            app.ViewdepthimageButton.Text = 'View depth image';
            app.AnalyseplantsButton = uibutton(app.UIFigure, 'push');
            app.AnalyseplantsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseplantsButtonPushed, true);
            app.AnalyseplantsButton.Text = 'Analyse plants';
            app.AnalyseallButton = uibutton(app.UIFigure, 'push');
            app.AnalyseallButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseallButtonPushed, true);
            app.AnalyseallButton.Text = 'Analyse all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plant_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end